classdef allamvizsga < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        JvbelibecslsButton      matlab.ui.control.Button
        MrtadatButton           matlab.ui.control.Button
        KomponensekButtonGroup  matlab.ui.container.ButtonGroup
        zButton                 matlab.ui.control.RadioButton
        yButton                 matlab.ui.control.RadioButton
        xButton                 matlab.ui.control.RadioButton
        PSOButton               matlab.ui.control.Button
        OsszehasonltsButton     matlab.ui.control.Button
        LogIntegrlButton        matlab.ui.control.Button
        IntegrlButton           matlab.ui.control.Button
        AdvancedButton          matlab.ui.control.Button
        AdatokbeolvassaButton   matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        input % Description
        time
        components = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AdatokbeolvassaButton
        function ReadDataButtonPushed(app, event)
            hold(app.UIAxes, 'off');
            app.input = readmatrix('data.txt');
            app.time = 0 : 1 : 18;
            plot(app.UIAxes, app.input(:, app.components), 'b-*');
            legend(app.UIAxes, 'Mért adat');
            app.UIAxes.YLim = [0.2 0.55];
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)

         if(isempty(app.input))
                errordlg('Nincs beolvasva adat!','File Error');
                return;
         end
            
         hold(app.UIAxes, 'off');

         advancedResult = [0.45 -0.6 -0.2 -0.66;
                           0.86 -0.02 -1.8 -0.59;
                           0.2 -0.06 -0.13 -0.5];

         [t, y] = ode45(@(t, y) Lotka_Volterra(t, y, advancedResult'), app.time, app.input(1, :));
            
          plot(app.UIAxes, y(:, app.components), 'b-*');
         
          legend(app.UIAxes, 'Advanced');
        end

        % Button pushed function: IntegrlButton
        function IntegrlButtonPushed(app, event)

            if(isempty(app.input))
            
                errordlg('Nincs beolvasva adat!','File Error');
                return;

            end

            hold(app.UIAxes, 'off');

            integralResult = IntegralMethod(app.input);

            [t, y] = ode45(@(t, y) Lotka_Volterra(t, y, integralResult), app.time, app.input(1, :));
            
            plot(app.UIAxes, y(:, app.components), 'b-*');
 
            legend(app.UIAxes, 'Integrál');
        end

        % Button pushed function: LogIntegrlButton
        function LogIntegrlButtonPushed(app, event)

            if(isempty(app.input))
            
                errordlg('Nincs beolvasva adat','File Error');
                return;

            end

            hold(app.UIAxes, 'off');
            
            logIntegralResult = LogIntegralMethod(app.input);
            
            [t, y] = ode45(@(t, y) Lotka_Volterra(t, y, logIntegralResult), app.time, app.input(1, :));
            
            plot(app.UIAxes, y(:, app.components), 'b-*');
  
            legend(app.UIAxes, 'Log Integrál');
        end

        % Button pushed function: OsszehasonltsButton
        function ComparisonButtonPushed(app, event)

            if(isempty(app.input))
            
                errordlg('Nincs beolvasva adat!','File Error');
                return;

            end

            hold(app.UIAxes, 'off');
            
            % Data
            
            plot(app.UIAxes, app.input(:, app.components), 'y-*');
            hold(app.UIAxes, 'on');
            
            % Advanced 
            
            advancedResult = [0.45 -0.6 -0.2 -0.66;
                              0.86 -0.02 -1.8 -0.59;
                              0.2 -0.06 -0.13 -0.5];

            [t, y] = ode45(@(t, y) Lotka_Volterra(t, y, advancedResult'), app.time, app.input(1, :));
            
            plot(app.UIAxes, y(:, app.components), 'r-*');
     
            hold(app.UIAxes, 'on');

            advancedMeanSquareError = mean((app.input - y).^2);
            
            % Integral method
            
            integralResult = IntegralMethod(app.input);
            
            [t, y] = ode45(@(t, y) Lotka_Volterra(t, y, integralResult), app.time, app.input(1, :));
            
            plot(app.UIAxes, y(:, app.components), 'g-*');
          
            hold(app.UIAxes, 'on');

            integralMeanSquareError = mean((app.input - y).^2);
            
            %Log Integral
            
            logIntegralResult = LogIntegralMethod(app.input);
            
            [t, y] = ode45(@(t, y) Lotka_Volterra(t, y, logIntegralResult), app.time, app.input(1, :));
            
            plot(app.UIAxes, y(:, app.components), 'b-*');
       
            hold(app.UIAxes, 'on');

            logIntegralMeanSquareError = mean((app.input - y).^2);

            % PSO

            A = readmatrix('pso.txt');
            %A= [0.8520   -0.8273   -0.5535   -1.3588;
            %0.5016    0.6043   -1.4662   -0.6328;
            % -1.0042   1.3681    1.1207    0.2644; ];


            if(isempty(A))
            
                errordlg('PSO hiba!','File Error');
                return;

            end

            f = @(t, w) [w(1) * (A(1, 1) + A(1, 2) * w(1) + A(1, 3) * w(2) + A(1, 4) * w(3));
            w(2) * (A(2, 1) + A(2, 2) * w(1) + A(2, 3) * w(2) + A(2, 4) * w(3));
            w(3) * (A(3, 1) + A(3, 2) * w(1) + A(3, 3) * w(2) + A(3, 4) * w(3))]; 

            [t, y] = ode45(f, app.time, app.input(1, :));  %a diff egynelet megoldasa

            plot(app.UIAxes, y(:, app.components), 'k-*');


            legend(app.UIAxes, 'Mért adat','Advanced', 'Integrál', 'Log Integrál', 'PSO');


            % mean square error

            psoMeanSquareError = mean((app.input - y).^2);

            meanSquareErrors = [advancedMeanSquareError; integralMeanSquareError; logIntegralMeanSquareError; psoMeanSquareError];

            %exportgraphics(app.UIFigure,'/Users/szilveszterdavid/Documents/Dokumentumok – Szilveszter MacBook Air/MATLAB/Osszehasonlitas.png')

            writematrix(meanSquareErrors, "meanSquareErrors.txt");
            
        end

        % Selection changed function: KomponensekButtonGroup
        function ComponentsButtonGroupSelectionChanged(app, event)
            %selectedButton = app.KomponensekButtonGroup.SelectedObject;
            if app.xButton.Value
                app.components = 1;
            end
            if app.yButton.Value
                app.components = 2;
            end
            if app.zButton.Value
                app.components = 3;
            end
        end

        % Button pushed function: PSOButton
        function PsoButtonPushed(app, event)

            if(isempty(app.input))
            
                errordlg('Nincs beolvasva adat!','File Error');
                return;

            end

            hold(app.UIAxes, 'off');

            A = PSO();

            %A= [0.8520   -0.8273   -0.5535   -1.3588;
            %0.5016    0.6043   -1.4662   -0.6328;
            % -1.0042   1.3681    1.1207    0.2644; ];


            f = @(t, w) [w(1)*(A(1, 1) + A(1, 2) * w(1) + A(1, 3) * w(2) + A(1, 4) * w(3));
            w(2)*  (A(2, 1) + A(2,2) * w(1) + A(2,3) * w(2) + A(2, 4) * w(3));
            w(3) * (A(3, 1) + A(3,2) * w(1) + A(3,3) * w(2) + A(3, 4) * w(3))]; 

            [t, y] = ode45(f, app.time, app.input(1, :));  %a diff egynelet megoldasa


            plot(app.UIAxes, y(:, app.components), 'b-*');

            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            legend(app.UIAxes, 'PSO');

            hold(app.UIAxes, 'on');

            writematrix(A, 'pso.txt');
     
        end

        % Button pushed function: MrtadatButton
        function DataButtonPushed(app, event)

            if(isempty(app.input))
            
                errordlg('Nincs beolvasva adat!','File Error');
                return;

            end

            hold(app.UIAxes, 'off');

            plot(app.UIAxes, app.input(:, app.components), 'b-*');
            legend(app.UIAxes, 'Mért adat');

            hold(app.UIAxes, 'on');
        end

        % Button pushed function: JvbelibecslsButton
        function JvbelibecslsButtonPushed(app, event)
            if(isempty(app.input))
            
                errordlg('Nincs beolvasva adat!','File Error');
                return;

            end

            time = 0 : 1 : 50;

            hold(app.UIAxes, 'off');
            
            % Data
            
            plot(app.UIAxes, app.input(:, app.components), 'y-*');
            hold(app.UIAxes, 'on');
            
            % Advanced 
            
            advancedResult = [0.45 -0.6 -0.2 -0.66;
                              0.86 -0.02 -1.8 -0.59;
                              0.2 -0.06 -0.13 -0.5];

            [t, y] = ode45(@(t, y) Lotka_Volterra(t, y, advancedResult'), time, app.input(1, :));
            
            plot(app.UIAxes, y(:, app.components), 'r-*');
     
            hold(app.UIAxes, 'on');
            
            % Integral method
            
            integralResult = IntegralMethod(app.input);
            
            [t, y] = ode45(@(t, y) Lotka_Volterra(t, y, integralResult), time, app.input(1, :));
            
            plot(app.UIAxes, y(:, app.components), 'g-*');
          
            hold(app.UIAxes, 'on');
            
            %Log Integral
            
            logIntegralResult = LogIntegralMethod(app.input);
            
            [t, y] = ode45(@(t, y) Lotka_Volterra(t, y, logIntegralResult), time, app.input(1, :));
            
            plot(app.UIAxes, y(:, app.components), 'b-*');
       
            hold(app.UIAxes, 'on');

            % PSO

            A = readmatrix('pso.txt');

            %A= [0.8520   -0.8273   -0.5535   -1.3588;
            %0.5016    0.6043   -1.4662   -0.6328;
            % -1.0042   1.3681    1.1207    0.2644; ];


            if(isempty(A))
            
                errordlg('PSO hiba!','File Error');
                return;

            end

            f = @(t,w) [w(1)*(A(1,1)+A(1,2)*w(1)+A(1,3)*w(2)+A(1,4)*w(3));
            w(2)*(A(2,1)+A(2,2)*w(1)+A(2,3)*w(2)+A(2,4)*w(3));
            w(3)*(A(3,1)+A(3,2)*w(1)+A(3,3)*w(2)+A(3,4)*w(3))]; 

            [t, y] = ode45(f, time, app.input(1, :));  %a diff egynelet megoldasa

            plot(app.UIAxes, y(:, app.components), 'k-*');


            legend(app.UIAxes, 'Mért adat','Advanced', 'Integrál', 'Log Integrál', 'PSO');

            %exportgraphics(app.UIFigure,'/Users/szilveszterdavid/Documents/Dokumentumok – Szilveszter MacBook Air/MATLAB/JovobeliBecsles1.png')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Görbeillesztés differenciálegyenletes modellek esetén')
            xlabel(app.UIAxes, 'Idő')
            ylabel(app.UIAxes, 'Mért adat')
            zlabel(app.UIAxes, 'Z')
            app.AdatokbeolvassaButton = uibutton(app.UIFigure, 'push');
            app.AdatokbeolvassaButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.AdatokbeolvassaButton.Text = 'Adatok beolvasása';
            app.AdvancedButton = uibutton(app.UIFigure, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            app.IntegrlButton = uibutton(app.UIFigure, 'push');
            app.IntegrlButton.ButtonPushedFcn = createCallbackFcn(app, @IntegrlButtonPushed, true);
            app.IntegrlButton.Text = 'Integrál';
            app.LogIntegrlButton = uibutton(app.UIFigure, 'push');
            app.LogIntegrlButton.ButtonPushedFcn = createCallbackFcn(app, @LogIntegrlButtonPushed, true);
            app.LogIntegrlButton.Text = 'Log Integrál';
            app.OsszehasonltsButton = uibutton(app.UIFigure, 'push');
            app.OsszehasonltsButton.ButtonPushedFcn = createCallbackFcn(app, @ComparisonButtonPushed, true);
            app.OsszehasonltsButton.Text = {'Módszerek'; ' összehasonlítása'};
            app.PSOButton = uibutton(app.UIFigure, 'push');
            app.PSOButton.ButtonPushedFcn = createCallbackFcn(app, @PsoButtonPushed, true);
            app.PSOButton.Text = 'PSO';
            app.KomponensekButtonGroup = uibuttongroup(app.UIFigure);
            app.KomponensekButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ComponentsButtonGroupSelectionChanged, true);
            app.KomponensekButtonGroup.Title = 'Komponensek';
            app.xButton = uiradiobutton(app.KomponensekButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.KomponensekButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.KomponensekButtonGroup);
            app.zButton.Text = 'z';
            app.MrtadatButton = uibutton(app.UIFigure, 'push');
            app.MrtadatButton.ButtonPushedFcn = createCallbackFcn(app, @DataButtonPushed, true);
            app.MrtadatButton.Text = 'Mért adat';
            app.JvbelibecslsButton = uibutton(app.UIFigure, 'push');
            app.JvbelibecslsButton.ButtonPushedFcn = createCallbackFcn(app, @JvbelibecslsButtonPushed, true);
            app.JvbelibecslsButton.Text = {'Jövőbeli '; 'becslés'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = allamvizsga

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end